classdef TEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        EPI                 matlab.ui.control.EditField
        ENL                 matlab.ui.control.EditField
        PSNR                matlab.ui.control.EditField
        Evaluate            matlab.ui.control.Button
        address_2           matlab.ui.control.EditField
        output              matlab.ui.control.Button
        filters             matlab.ui.container.ButtonGroup
        UnusefulButton      matlab.ui.control.RadioButton
        GammamapFilter      matlab.ui.control.RadioButton
        LeeFilter           matlab.ui.control.RadioButton
        KuanFilter          matlab.ui.control.RadioButton
        FrostButton         matlab.ui.control.RadioButton
        MeanFilter          matlab.ui.control.RadioButton
        MiddleFingerFilter  matlab.ui.control.RadioButton
        address             matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        import              matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        Resultimg           matlab.ui.control.Image
        Image               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Property % Description
        num2=1859864;
        num=1;
        imga="";
        n_path="";
        inpath="";
        outpath="";
        infilename="";
        outfilepath="";
    end

    % function ImgResult=Meanfilter(Img,Size,WindowSize)
    %
    % end
    methods (Access = private)
        function resultImg = MeanFilters(app, I, windowSize)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）

            % 确保窗口大小是奇数
            if mod(windowSize, 2) == 0
                windowSize = windowSize + 1;
            end

            % 记录输入图像的数据类型
            inputDataType = class(I);

            % 图像尺寸
            [x, y] = size(I);
            N = zeros(x, y);

            % 填充边界
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');

            % 创建进度条
            hWaitBar = waitbar(0, 'Processing Mean Filter...', 'Name', 'Mean Filter Progress');

            try
                % 滤波处理
                for i = 1 + padSize : x + padSize
                    for j = 1 + padSize : y + padSize
                        window = I(i - padSize : i + padSize, j - padSize : j + padSize);
                        % 计算局部均值
                        localMean = mean(window(:));
                        N(i - padSize, j - padSize) = localMean;
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end

                % 关闭进度条
                close(hWaitBar);

            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end

            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
            [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = [app.infilename '_MeanFilterresult' ext];             % 使用相同扩展名保存结果
            
            if strlength(app.outpath)<3
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);


            % 弹窗显示保存成功消息
            msgbox(['图片已保存为： ' outputFileName], '保存成功');
        end


        function resultImg = MedianFilters(app,I, windowSize)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）

            if windowSize/2==0
                windowSize=windowSize-1;
            end

            % 记录输入图像的数据类型
            inputDataType = class(I);

            [x, y] = size(I);
            N = zeros(x, y);
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');

            % 创建进度条
            hWaitBar = waitbar(0, 'Processing Media Filter...', 'Name', 'Median Filter Progress');

            try
                for i = 1+(windowSize-1)/2:x+(windowSize-1)/2
                    for j = 1+(windowSize-1)/2:y+(windowSize-1)/2
                        window = I(i-(windowSize-1)/2:i+(windowSize-1)/2, j-(windowSize-1)/2:j+(windowSize-1)/2);
                        N(i-(windowSize-1)/2,j-(windowSize-1)/2)=median(window(:));
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end

                % 关闭进度条
                close(hWaitBar);

            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end
            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
             [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = [app.infilename '_MedianFilterresult' ext];             % 使用相同扩展名保存结果
            
            if strlength(app.outpath)<3
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);


            % 弹窗显示保存成功消息
            msgbox(['图片已保存为： ' outputFileName], '保存成功');

        end


        function resultImg = Leefilter(app, I, windowSize)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）
            % 记录输入图像的数据类型
            inputDataType = class(I);
            [x, y] = size(I);
            N = zeros(x, y);
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');
            %用滤波窗口的倒数作为斑点噪声的局部方差系数
            Cf=1/windowSize;

            % 创建进度条
            hWaitBar = waitbar(0, 'Processing Lee Filter...', 'Name', 'Lee Filter Progress');

            try
                for i = 1+(windowSize-1)/2:x+(windowSize-1)/2
                    for j = 1+(windowSize-1)/2:y+(windowSize-1)/2
                        window = I(i-(windowSize-1)/2:i+(windowSize-1)/2, j-(windowSize-1)/2:j+(windowSize-1)/2);
                        % 计算局部均值和方差
                        window=double(window);
                        localMean = mean(window(:));
                        localVar = var(window(:));
                        %计算局部方差系数
                        Ci=localVar / localMean^2;
                        if(localMean == 0)
                            k = 0;
                        else
                            k = (1 - (Cf^2)/(Ci^2));
                        end
                        if(k<0)
                            k = 0;
                        end
                        N(i-(windowSize-1)/2,j-(windowSize-1)/2) = localMean + k*(I(i,j) - localMean);
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end
                % 关闭进度条
                close(hWaitBar);

            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end

            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
            [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = [app.infilename '_LeeFilterresult' ext];             % 使用相同扩展名保存结果

          if strlength(app.outpath)<3
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);


            % 弹窗显示保存成功消息
            msgbox(['图片已保存为：' outputFileName], '保存成功');
        end


        function resultImg=Kuanfilter(app, I, windowSize)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）

            % 记录输入图像的数据类型
            inputDataType = class(I);
            [x, y] = size(I);
            N = zeros(x, y);
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');
            I =double(I);
            % 创建进度条
            hWaitBar = waitbar(0, 'Processing Kuan Filter...', 'Name', 'Kuan Filter Progress');

            try
                for i = 1+(windowSize-1)/2:x+(windowSize-1)/2
                    for j = 1+(windowSize-1)/2:y+(windowSize-1)/2
                        window = I(i-(windowSize-1)/2:i+(windowSize-1)/2, j-(windowSize-1)/2:j+(windowSize-1)/2);
                        % 计算局部均值和方差
                        localMean = mean(window(:));
                        localVar = var(window(:));
                        % 计算权重
                        w = exp(-((window - localMean).^2) / (2 * localVar));
                        % 应用Kuan滤波
                        N(i-(windowSize-1)/2,j-(windowSize-1)/2) = sum(w(:) .* window(:)) / sum(w(:));
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end
                % 关闭进度条
                close(hWaitBar);

            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end

            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
            [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = [app.infilename '_KuanFilterresult' ext];             % 使用相同扩展名保存结果

            if strlength(app.outpath)<3
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);

            % 弹窗显示保存成功消息
            msgbox(['图片已保存为：' outputFileName], '保存成功');
        end


        function resultImg = GammaMAPFilter(app, I, windowSize)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）
            % 记录输入图像的数据类型
            inputDataType = class(I);
            I = double(I);
            [x, y] = size(I);
            N = zeros(x, y);
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');

            % 创建进度条
            hWaitBar = waitbar(0, 'Processing GammaMAP Filter...', 'Name', 'GammaMAP Filter Progress');

            try
                % 遍历图像的每个像素
                for i = 1 + padSize : x + padSize
                    for j = 1 + padSize : y + padSize
                        window = I(i - padSize:i + padSize, j - padSize:j + padSize);
                        window = double(window);
                        % 计算局部均值和方差
                        localMean = mean(window(:));
                        localVar = var(window(:));

                        % 计算局部方差系数
                        C = localVar / localMean^2;

                        % Gamma MAP滤波公式
                        if C > 1
                            alpha = 2 / (C - 1);
                            beta = alpha * localMean;
                            gamma = localMean^2 + 8 * alpha * I(i, j) * localMean;
                            N(i - padSize, j - padSize) = ((beta - 2) * localMean + sqrt(gamma)) / (2 * beta);
                        else
                            % 如果方差系数不大于1，保留原像素值
                            N(i - padSize, j - padSize) = I(i, j);
                        end
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end
                % 关闭进度条
                close(hWaitBar);

            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end

            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
            [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = [app.infilename '_GammaMAPFilterresult' ext];             % 使用相同扩展名保存结果

            if strlength(app.outpath)<1
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);


            % 弹窗显示保存成功消息
            msgbox(['图片已保存为： ' outputFileName], '保存成功');
        end


        function resultImg=FrostFilter(app,I, windowSize, alpha)
            % I: 输入的SAR图像
            % windowSize: 滤波窗口的大小（奇数）
            % alpha: 是一个微调因子，值越小，则边缘的保持效果不好，而斑点噪声平滑效果好；
            % alpha值越大，则边缘的保持效果越好，而斑点噪声平滑效果越差。

            % 记录输入图像的数据类型
            inputDataType = class(I);

            [x, y] = size(I);
            N = zeros(x, y);
            padSize = floor(windowSize / 2);
            I = padarray(I, [padSize padSize], 'symmetric');
            I= double(I);
            % 创建进度条
            hWaitBar = waitbar(0, 'Processing Frost Filter...', 'Name', 'Frost Filter Progress');

            try
                % 创建距离矩阵 (欧氏距离)
                distances = zeros(windowSize, windowSize);
                for i = 1:windowSize
                    for j = 1:windowSize
                        distances(i,j) = sqrt((i - (windowSize + 1) / 2)^2 + (j - (windowSize + 1) / 2)^2);
                    end
                end

                % 处理图像
                for i = 1 + (windowSize - 1) / 2 : x + (windowSize - 1) / 2
                    for j = 1 + (windowSize - 1) / 2 : y + (windowSize - 1) / 2
                        % 提取当前窗口
                        window = double(I(i - (windowSize - 1) / 2 : i + (windowSize - 1) / 2, ...
                            j - (windowSize - 1) / 2 : j + (windowSize - 1) / 2));
                        % 应用 Frost 滤波
                        N(i - (windowSize - 1) / 2, j - (windowSize - 1) / 2) = ...
                            sum(window .* exp(-alpha * distances.^2), 'all') / sum(exp(-alpha * distances.^2), 'all');
                    end
                    % 更新进度条
                    waitbar((i - padSize) / x, hWaitBar);
                end

                % 关闭进度条
                close(hWaitBar);
            catch ME
                % 若发生错误，关闭进度条并抛出错误
                close(hWaitBar);
                rethrow(ME);
            end

            % 转换结果为输入图像的数据类型
            resultImg = cast(N, inputDataType);

            % 获取输入图像的扩展名
            [~, ~, ext] = fileparts(app.n_path); % 转换为字符以支持 fileparts
            outputFileName = ['FrostFilterresult' ext];             % 使用相同扩展名保存结果

            if strlength(app.outpath)<3
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = app.inpath;
            end
            if ~endsWith(app.outpath, filesep)
                app.outpath = app.outpath+"\";
            end
           
            % 构建完整的文件路径
            fullOutputPath = strcat(app.outpath,outputFileName);
            app.outfilepath = fullOutputPath;
                       
            % 保存图像到指定路径
            imwrite(resultImg, fullOutputPath);

            % 弹窗显示保存成功消息
            msgbox(['图片已保存为： ' outputFileName], '保存成功');
        end

        function [PSNR,ENL,EPI] = Evaluates(app,I,N)
            %峰值信噪比（PSNR）、信噪比增强（ENL）和边缘保持指标（EPI）
            %较高的 PSNR 表示较低误差，
            %较高的 ENL 表示较好的去噪效果，
            %接近 1 的 EPI 表示边缘信息得到了较好的保留。

            f1=double(I);
            f2=double(N);
            [x,y]=size(f1);
            G1=double(f1);
            G2=double(f2);
            [m1,n1]=size(G1);
            [m2,n2]=size(G2);
            m=min(m1,m2);
            n=min(n1,n2);
            b=0;
            b=max(G1(:));
            c=0;
            for i=1:m
                for j=1:n
                    w=G1(i,j)-G2(i,j);
                    c=c+w^2;
                end
            end
            if c==0
                c=1;
            end               
            PSNR=10*log10(b^2*m*n/c);
            ENL=mean(N(:))^2/var(N(:));
            sum1=0;
            sum2=0;
            %去噪前
            for i=1:x   %水平
                for j=1:y-1
                    sum1=sum1+abs(I(i,j)-I(i,j+1));
                end
            end
            for i=1:x-1  %垂直
                for j=1:y
                    sum1=sum1+abs(I(i,j)-I(i+1,j));
                end
            end

            for i=1:x  %水平
                for j=1:y-1
                    sum2=sum2+abs(N(i,j)-N(i,j+1));
                end
            end
            for i=1:x-1  %垂直
                for j=1:y
                    sum2=sum2+abs(N(i,j)-N(i+1,j));
                end
            end
            sum1=double(sum1);
            sum2=double(sum2);
            EPI=sum2/sum1;
            PSNR=string(num2str(PSNR));
            ENL=string(num2str(ENL));
            EPI=string(num2str(EPI));
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: import
        function importButtonPushed(app, event)
            [file, path] = uigetfile({'*.TIF;*.tif','TIF Files (*.TIF;*.tif)'; ...
                '*.jpg;*.jpeg','JPEG Files (*.jpg;*.jpeg)'; ...
                '*.png','PNG Files (*.png)'; ...
                '*.bmp','BMP Files (*.bmp)'; ...
                '*.gif','GIF Files (*.gif)'; ...
                '*.*','All Files (*.*)'}, '打开图片文件');

            if isequal(file, 0)
                msgbox('请选择一张图片！');
            else
                fullPath = fullfile(path, file);
                app.inpath=path;
                [~, app.infilename, ~] = fileparts(file);
                if isempty(app.address_2.Value)
                    app.address_2.Value = path;
                end
                app.address.Value = fullPath;
                app.n_path=fullPath;

                % 读取图像
                img = imread(fullPath);
                app.imga=imread(fullPath);

                % 如果是灰度图像（只有二维数组），需要转为RGB格式
                if size(img, 3) == 1
                    img = cat(3, img, img, img);  % 将灰度图像扩展为RGB
                end
                if app.num2==1859864
                    app.UnusefulButton.Value=1;
                end
                if isempty(app.outpath)
                    % 用户选择了文件夹，将路径赋值给 filepath
                    app.outpath = app.inpath;
                end
                % 显示图像
                app.Image.ImageSource = img;  % 图像控件显示图像数据
            end
        end

        % Value changed function: address
        function addressValueChanged(app, event)
            % 获取输入地址
            value = app.address.Value;

            % 检查文件路径是否有效
            if isempty(value) || ~isfile(value)
                % 提示用户文件路径无效
                errordlg('无效的文件路径。请提供有效的文件路径.', 'Error');
                return;
            end

            % 检查文件是否是图片格式
            [~, ~, ext] = fileparts(value);  % 获取文件扩展名
            validExtensions = {'.jpg', '.jpeg', '.png', '.bmp', '.tif', '.tiff'};  % 支持的图片格式
            if ~ismember(lower(ext), validExtensions)
                % 提示用户文件不是图片
                errordlg('所选文件不是有效的图像文件,请选择一个图像文件。', 'Error');
                return;
            end

            % 尝试读取图像文件
            try
                img = imread(value);  % 尝试读取图像
            catch
                % 如果读取失败，提示用户文件可能损坏
                errordlg('读取失败，文件可能损坏', 'Error');
                return;
            end

            % 如果是灰度图像（二维数组），转换为RGB格式
            if size(img, 3) == 1
                img = cat(3, img, img, img);  % 将灰度图像扩展为RGB
            end

            % 添加额外逻辑
            if app.num2 == 1859864
                app.UnusefulButton.Value = 1;
            end

            % 更新UI中的图像显示
            app.Image.ImageSource = img;

            % 提示用户成功加载图片
            msgbox('导入图片成功!', 'Success');

        end

        % Selection changed function: filters
        function filtersSelectionChanged(app, event)
            selectedButton = app.filters.SelectedObject;
            if isempty(app.address.Value)
                msgbox('请导入图像！', '图像未加载', 'warn');
                return;  % 如果图像未加载，终止函数执行
            end
            % 判断哪个按钮被选中
            switch selectedButton
                case app.UnusefulButton
                    app.num=0;
                case app.GammamapFilter
                    app.num = 1; % 假设Gamma MAP对应的值是1
                case app.LeeFilter
                    app.num = 2; % 假设Lee对应的值是2
                case app.KuanFilter
                    app.num = 3; % 假设Kuan对应的值是3
                case app.FrostButton
                    app.num = 4; % 假设Frost对应的值是4
                case app.MeanFilter
                    app.num = 5; % 假设Mean对应的值是5
                case app.MiddleFingerFilter
                    app.num = 6; % 假设Median对应的值是6
                otherwise
                    app.num = 1; % 默认值或未选择
            end

            % 根据num的值弹出相应的参数选择窗口
            switch app.num
                case 1  % Gamma MAP Filter
                    % 显示Gamma MAP Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:'};
                    dlgTitle = 'Gamma MAP Filter 参数';
                    dims = [1 35];
                    definput = {'3'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        %alpha = str2double(answer{2});
                        % 调用Gamma MAP滤波函数
                        resultImg = app.GammaMAPFilter(app.imga, windowSize);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end

                case 2  % Lee Filter
                    % 显示Lee Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:'};
                    dlgTitle = 'Lee Filter 参数';
                    dims = [1 35];
                    definput = {'3'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        %alpha = str2double(answer{1});
                        % 调用Lee滤波函数
                        resultImg = app.Leefilter(app.imga, windowSize);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end

                case 3  % Kuan Filter
                    % 显示Kuan Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:'};
                    dlgTitle = 'Kuan Filter 参数';
                    dims = [1 35];
                    definput = {'3'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        %alpha = str2double(answer{2});
                        % 调用Kuan滤波函数
                        resultImg = app.Kuanfilter(app.imga, windowSize);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end

                case 4  % Frost Filter
                    % 显示Frost Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:', '输入alpha值:'};
                    dlgTitle = 'Frost Filter 参数';
                    dims = [1 35];
                    definput = {'3', '0.1'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        alpha = str2double(answer{2});
                        % 调用Frost滤波函数
                        resultImg = app.FrostFilter(app.imga, windowSize, alpha);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end

                case 5  % Mean Filter
                    % 显示Mean Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:'};
                    dlgTitle = 'Mean Filter 参数';
                    dims = [1 35];
                    definput = {'3'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        % 调用Mean滤波函数
                        resultImg = app.MeanFilters(app.imga, windowSize);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end

                case 6  % Median Filter
                    % 显示Median Filter参数输入窗口
                    prompt = {'输入滤波窗口大小:'};
                    dlgTitle = 'Median Filter 参数';
                    dims = [1 35];
                    definput = {'3'};
                    answer = inputdlg(prompt, dlgTitle, dims, definput);
                    if ~isempty(answer)
                        windowSize = str2double(answer{1});
                        % 调用Median滤波函数
                        resultImg = app.MedianFilters(app.imga, windowSize);
                        % 如果是灰度图像（只有二维数组），需要转为RGB格式
                        if size(resultImg, 3) == 1
                            resultImg = cat(3, resultImg, resultImg, resultImg);  % 将灰度图像扩展为RGB
                        end

                        % 显示图像
                        app.Resultimg.ImageSource = resultImg;  % 图像控件显示图像数据
                    end
                otherwise
                    % 默认行为
                    msgbox('未选择滤波器或无效选项');
            end

        end

        % Value changed function: address_2
        function address_2ValueChanged(app, event)
            value = app.address_2.Value;
            if exist(value, 'dir') == 7
                % 如果 outpath 是有效的文件夹路径
                app.outpath=value;
            else
                % 如果 outpath 不是有效的文件夹路径
                errordlg('无效的文件夹路径。请提供有效的文件夹路径.', 'Error');
            end
        end

        % Button pushed function: output
        function outputButtonPushed(app, event)
            folderPath = uigetdir();

            % 检查用户是否选择了文件夹
            if folderPath ~= 0
                % 用户选择了文件夹，将路径赋值给 filepath
                app.outpath = folderPath;
                app.address_2.Value=app.outpath;
                % msgbox(app.outpath)
                % 可以在这里添加其他操作，例如更新界面上的文本框显示路径等
            else
                % 用户取消了选择
                msgbox('用户取消了文件夹选择');
            end
        end

        % Button pushed function: Evaluate
        function m_Evaluate(app, event)
            % msgbox([app.address.Value   app.outfilepath]);
            [file1, path1] = uigetfile({'*.TIF;*.tif','TIF Files (*.TIF;*.tif)'; ...
                '*.jpg;*.jpeg','JPEG Files (*.jpg;*.jpeg)'; ...
                '*.png','PNG Files (*.png)'; ...
                '*.bmp','BMP Files (*.bmp)'; ...
                '*.gif','GIF Files (*.gif)'; ...
                '*.*','All Files (*.*)'}, '打开原始影像图片文件');
            [file2, path2] = uigetfile({'*.TIF;*.tif','TIF Files (*.TIF;*.tif)'; ...
                '*.jpg;*.jpeg','JPEG Files (*.jpg;*.jpeg)'; ...
                '*.png','PNG Files (*.png)'; ...
                '*.bmp','BMP Files (*.bmp)'; ...
                '*.gif','GIF Files (*.gif)'; ...
                '*.*','All Files (*.*)'}, '打开处理后的图片文件');

            if isequal(file1, 0)
                msgbox('请选择原始影像！');
            else
                fullPath1 = fullfile(path1, file1);
            end
            if isequal(file1, 0)
                msgbox('请选择处理后的影像！');
            else
                fullPath2 = fullfile(path2, file2);
            end
             I=imread(fullPath1);
             N=imread(fullPath2);
             I=double(I);
             N=double(I);
             [app.PSNR.Value, app.ENL.Value, app.EPI.Value] = app.Evaluates(I,N);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.import = uibutton(app.LeftPanel, 'push');
            app.import.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.import.Tooltip = {'点击选择图像'};
            app.import.Text = '导入图像';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '文件地址';
            app.address = uieditfield(app.LeftPanel, 'text');
            app.address.ValueChangedFcn = createCallbackFcn(app, @addressValueChanged, true);
            app.address.Tooltip = {'手动输入文件地址，然后回车即可输入图像。'};
            app.filters = uibuttongroup(app.LeftPanel);
            app.filters.SelectionChangedFcn = createCallbackFcn(app, @filtersSelectionChanged, true);
            app.filters.Tooltip = {'选择一个滤波器，在弹窗中输入参数。'};
            app.filters.Title = '选择滤波操作';
            app.MiddleFingerFilter = uiradiobutton(app.filters);
            app.MiddleFingerFilter.Tooltip = {''};
            app.MiddleFingerFilter.Text = '中值滤波';
            app.MiddleFingerFilter.Value = true;
            app.MeanFilter = uiradiobutton(app.filters);
            app.MeanFilter.Text = '均值滤波';
            app.FrostButton = uiradiobutton(app.filters);
            app.FrostButton.Text = 'Frost滤波';
            app.KuanFilter = uiradiobutton(app.filters);
            app.KuanFilter.Text = 'Kuan滤波';
            app.LeeFilter = uiradiobutton(app.filters);
            app.LeeFilter.Text = 'Lee滤波';
            app.GammamapFilter = uiradiobutton(app.filters);
            app.GammamapFilter.Text = 'Gammamap滤波';
            app.UnusefulButton = uiradiobutton(app.filters);
            app.UnusefulButton.Visible = 'off';
            app.UnusefulButton.Text = 'Unuseful';
            app.output = uibutton(app.LeftPanel, 'push');
            app.output.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.output.Tooltip = {'点击选择图像'};
            app.output.Text = '选择保存路径';
            app.address_2 = uieditfield(app.LeftPanel, 'text');
            app.address_2.ValueChangedFcn = createCallbackFcn(app, @address_2ValueChanged, true);
            app.address_2.Tooltip = {'手动输入文件地址，然后回车即可。'};
            app.Evaluate = uibutton(app.LeftPanel, 'push');
            app.Evaluate.ButtonPushedFcn = createCallbackFcn(app, @m_Evaluate, true);
            app.Evaluate.Tooltip = {'点击选择图像'};
            app.Evaluate.Text = '结果评价';
            app.PSNR = uieditfield(app.LeftPanel, 'text');
            app.PSNR.Tooltip = {'峰值信噪比'};
            app.PSNR.Value = 'PSNR';
            app.ENL = uieditfield(app.LeftPanel, 'text');
            app.ENL.Tooltip = {'信噪比增强'};
            app.ENL.Value = '  ENL';
            app.EPI = uieditfield(app.LeftPanel, 'text');
            app.EPI.Tooltip = {'边缘保持指标'};
            app.EPI.Value = '   EPI';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Resultimg = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end