classdef TEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        EditField           matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        filters             matlab.ui.container.ButtonGroup
        GammamapFilter      matlab.ui.control.RadioButton
        LeeFilter           matlab.ui.control.RadioButton
        KuanFilter          matlab.ui.control.RadioButton
        FrostButton         matlab.ui.control.RadioButton
        MeanFilter          matlab.ui.control.RadioButton
        MiddleFingerFilter  matlab.ui.control.RadioButton
        address             matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        import              matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        Resultimg           matlab.ui.control.Image
        Image               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        num=1;
    end
    % function ImgResult=Meanfilter(Img,Size,WindowSize)
    % 
    % end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: import
        function importButtonPushed(app, event)
  [file, path] = uigetfile({'*.TIF;*.tif','TIF Files (*.TIF;*.tif)'; ...
                          '*.jpg;*.jpeg','JPEG Files (*.jpg;*.jpeg)'; ...
                          '*.png','PNG Files (*.png)'; ...
                          '*.bmp','BMP Files (*.bmp)'; ...
                          '*.gif','GIF Files (*.gif)'; ...
                          '*.*','All Files (*.*)'}, '打开图片文件');

if isequal(file, 0)
    msgbox('请选择一张图片！');
else
    fullPath = fullfile(path, file);
    app.address.Value = fullPath;
    app.Image.ImageSource = app.address.Value;
end
        end

        % Value changed function: address
        function addressValueChanged(app, event)
            value = app.address.Value;
            app.Image.ImageSource = app.address.Value;
        end

        % Selection changed function: filters
        function filtersSelectionChanged(app, event)
            selectedButton = app.filters.SelectedObject;
    switch event.Value
        case app.GammamapFilter
            num = 1; % 假设Gamma MAP对应的值是1
        case app.LeeFilter
            num = 2; % 假设Lee对应的值是2
        case app.KuanFilter
            num = 3; % 假设Kuan对应的值是3
        case app.FrostButton
            num = 4; % 假设Frost对应的值是4
        case app.MeanFilter
            num = 5; % 假设Mean对应的值是5
        case app.MiddleFingerFilter
            num = 6; % 假设Median对应的值是6
        otherwise
            num = 1; % 默认值或未选择
    end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.import = uibutton(app.LeftPanel, 'push');
            app.import.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.import.Tooltip = {'点击选择图像'};
            app.import.Text = '导入图像';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '文件地址';
            app.address = uieditfield(app.LeftPanel, 'text');
            app.address.ValueChangedFcn = createCallbackFcn(app, @addressValueChanged, true);
            app.address.Tooltip = {'手动输入文件地址，然后回车即可输入图像。'};
            app.filters = uibuttongroup(app.LeftPanel);
            app.filters.SelectionChangedFcn = createCallbackFcn(app, @filtersSelectionChanged, true);
            app.filters.Tooltip = {'选择一个滤波器，选择后将在下方显示，要输入的参数。'};
            app.filters.Title = '选择滤波操作';
            app.MiddleFingerFilter = uiradiobutton(app.filters);
            app.MiddleFingerFilter.Tooltip = {''};
            app.MiddleFingerFilter.Text = '中值滤波';
            app.MiddleFingerFilter.Value = true;
            app.MeanFilter = uiradiobutton(app.filters);
            app.MeanFilter.Text = '均值滤波';
            app.FrostButton = uiradiobutton(app.filters);
            app.FrostButton.Text = 'Frost滤波';
            app.KuanFilter = uiradiobutton(app.filters);
            app.KuanFilter.Text = 'Kuan滤波';
            app.LeeFilter = uiradiobutton(app.filters);
            app.LeeFilter.Text = 'Kuan滤波';
            app.GammamapFilter = uiradiobutton(app.filters);
            app.GammamapFilter.Text = 'Kuan滤波';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Resultimg = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end